classdef Menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SUDOKULabel            matlab.ui.control.Label
        ExitButton             matlab.ui.control.Button
        StartGameButton        matlab.ui.control.Button
        DifficultyButtonGroup  matlab.ui.container.ButtonGroup
        EasyButton             matlab.ui.control.ToggleButton
        MediumButton           matlab.ui.control.ToggleButton
        HardButton             matlab.ui.control.ToggleButton
    end


    properties (Access = public)
        Difficulty % Variable storing the difficulty level of the sudoku
    end

    methods (Access = private)
        
    end


    methods (Access = private)

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app); % Exits the app
        end

        % Selection changed function: DifficultyButtonGroup
        function DifficultyButtonGroupSelectionChanged(app, event)
            selectedButton = app.DifficultyButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'Easy'
                    app.Difficulty = 5;
                case 'Medium'
                    app.Difficulty = 6;
                case 'Hard'
                    app.Difficulty = 7;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SUDOKULabel = uilabel(app.UIFigure);
            app.SUDOKULabel.Text = 'SUDOKU';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.Text = 'Start Game';
            app.DifficultyButtonGroup = uibuttongroup(app.UIFigure);
            app.DifficultyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DifficultyButtonGroupSelectionChanged, true);
            app.DifficultyButtonGroup.Title = 'Difficulty';
            app.EasyButton = uitogglebutton(app.DifficultyButtonGroup);
            app.EasyButton.Text = 'Easy';
            app.MediumButton = uitogglebutton(app.DifficultyButtonGroup);
            app.MediumButton.Text = 'Medium';
            app.MediumButton.Value = true;
            app.HardButton = uitogglebutton(app.DifficultyButtonGroup);
            app.HardButton.Text = 'Hard';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Menu

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end