classdef SudokuScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ReturntoMenuButton  matlab.ui.control.Button
        PauseResumeButton   matlab.ui.control.Button
        TimeElapsedLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        SolutionGrid % Generated Sudoku grid that is the solution for the game
        UserGrid % Sudoku grid that the user will work with
        sudokuTimer % Description
    end
    
    methods (Access = private)
        
        function createDiagonalGrid(app)
            % Creates empty sudoku grid with the diagonal sections filled with random numbers
            app.SolutionGrid = zeros(9,9);
            for i = 1:3:7
                app.SolutionGrid(i:i+2,i:i+2) = reshape(randperm(9),3,3);
            end
        end
        
        function fillNonDiagonal(app)
            % Fills the non diagonal sections of the grid by successively checking the numbers 1-9 until a safe number is found
            for row = 1:9
                for col = 1:9
                    % Check if current cell is empty
                    if app.SolutionGrid(row,col) == 0
                        % If it's empty, sequentially check values from 1-9 until a safe one is found
                        for candidate = 1:9
                            % Check row, column and section (respectively)
                            isec = (ceil(row/3)-1)*3;
                            jsec = (ceil(col/3)-1)*3;
                            if ~any(ismember(candidate,app.SolutionGrid(row,:))) && ~any(ismember(candidate,app.SolutionGrid(:,col))) && ~any(ismember(candidate,app.SolutionGrid(isec+1:isec+3,jsec+1:jsec+3)))
                                app.SolutionGrid(row,col) = candidate;
                            end
                        end
                        
                    end
                    
                end
            end
            
        end
        
        
        function createUserSudoku(app, difficulty)
            % Creates the grid that will be shown to the user by removing values from the SolutionGrid.
            % The number of values to be removed will be difficulty*2. They will be removed randomly.
            app.UserGrid = app.SolutionGrid;
            for i = 1:difficulty*2
                row = randi(9);
                col = randi(9);
                % Randomly look for a non-empty position in the grid
                while app.UserGrid(row,col) == 0
                    row = randi(9);
                    col = randi(9);
                end
                app.UserGrid(row,col) = 0;
            end
            
        end
        
        function generateSudoku(app,difficulty)
            createDiagonalGrid(app);
            fillNonDiagonal(app);
            createUserSudoku(app,difficulty);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, difficulty)
            generateSudoku(app, difficulty);
            
            t = 0;
            app.sudokuTimer = timer('ExecutionMode','fixedRate','Period',1,'TimerFcn',{@stopwatch,app});
            start(app.sudokuTimer)
            
            function stopwatch(obj, event, app)
                t = t+1;
                sec = mod(t,60);
                min = mod(floor(t/60),60);
                h = floor(t/3600);
                app.TimeElapsedLabel.Text = [num2str(h), ':', num2str(min), ':', num2str(sec)];
            end
            
        end

        % Button pushed function: ReturntoMenuButton
        function ReturntoMenuButtonPushed(app, event)
            Menu();
            stop(app.sudokuTimer);
            delete(app.sudokuTimer);
            delete(app);
        end

        % Button pushed function: PauseResumeButton
        function PauseResumeButtonPushed(app, event)
            switch app.PauseResumeButton.Text
                case 'Pause'
                    app.PauseResumeButton.Text = 'Resume';
                    stop(app.sudokuTimer);
                case 'Resume'
                    app.PauseResumeButton.Text = 'Pause';
                    start(app.sudokuTimer);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ReturntoMenuButton = uibutton(app.UIFigure, 'push');
            app.ReturntoMenuButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoMenuButtonPushed, true);
            app.ReturntoMenuButton.Text = 'Return to Menu';
            app.PauseResumeButton = uibutton(app.UIFigure, 'push');
            app.PauseResumeButton.ButtonPushedFcn = createCallbackFcn(app, @PauseResumeButtonPushed, true);
            app.PauseResumeButton.Text = 'Pause';
            app.TimeElapsedLabel = uilabel(app.UIFigure);
            app.TimeElapsedLabel.Text = 'Time Elapsed';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SudokuScreen(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end